classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ActivityLevelDropDown         matlab.ui.control.DropDown
        ActivityLevelDropDownLabel    matlab.ui.control.Label
        TotalCaloriesBurnedEditField  matlab.ui.control.NumericEditField
        TotalCaloriesBurnedEditFieldLabel  matlab.ui.control.Label
        CalculateButton               matlab.ui.control.StateButton
        GenderDropDown                matlab.ui.control.DropDown
        GenderDropDownLabel           matlab.ui.control.Label
        AgeyearsEditField             matlab.ui.control.NumericEditField
        AgeyearsEditFieldLabel        matlab.ui.control.Label
        HeightcmEditField             matlab.ui.control.NumericEditField
        HeightcmEditFieldLabel        matlab.ui.control.Label
        WeightkgEditField             matlab.ui.control.NumericEditField
        WeightkgEditFieldLabel        matlab.ui.control.Label
        PleaseenterthefollowinginformationLabel  matlab.ui.control.Label
        FitnessTrackerLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CalculateButton
        function CalculateButtonValueChanged(app, event)
            %Assign variables and store them into struct
            userData.weight = app.WeightkgEditField.Value;
            userData.height = app.HeightcmEditField.Value;
            userData.age = app.AgeyearsEditField.Value;
            userData.gender = app.GenderDropDown.Value;
            userData.activityLvl = app.ActivityLevelDropDown.Value;
            userData.totalCaloriesEaten = 0;

            %Call function to calculate total calories burned
            app.TotalCaloriesBurnedEditField.Value = calculateTDEE(userData);

            %Save value to struct
            userData.TDEE = app.TotalCaloriesBurnedEditField.Value;

            %Save struct as file to use later
            save('userData.mat', 'userData');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FitnessTrackerLabel = uilabel(app.UIFigure);
            app.FitnessTrackerLabel.Text = 'Fitness Tracker';
            app.PleaseenterthefollowinginformationLabel = uilabel(app.UIFigure);
            app.PleaseenterthefollowinginformationLabel.Text = 'Please enter the following information';
            app.WeightkgEditFieldLabel = uilabel(app.UIFigure);
            app.WeightkgEditFieldLabel.Text = 'Weight (kg)';
            app.WeightkgEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightcmEditFieldLabel = uilabel(app.UIFigure);
            app.HeightcmEditFieldLabel.Text = 'Height (cm)';
            app.HeightcmEditField = uieditfield(app.UIFigure, 'numeric');
            app.AgeyearsEditFieldLabel = uilabel(app.UIFigure);
            app.AgeyearsEditFieldLabel.Text = 'Age (years)';
            app.AgeyearsEditField = uieditfield(app.UIFigure, 'numeric');
            app.GenderDropDownLabel = uilabel(app.UIFigure);
            app.GenderDropDownLabel.Text = 'Gender';
            app.GenderDropDown = uidropdown(app.UIFigure);
            app.GenderDropDown.Items = {'Male ', 'Female'};
            app.GenderDropDown.Value = 'Male ';
            app.CalculateButton = uibutton(app.UIFigure, 'state');
            app.CalculateButton.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonValueChanged, true);
            app.CalculateButton.Text = 'Calculate';
            app.TotalCaloriesBurnedEditFieldLabel = uilabel(app.UIFigure);
            app.TotalCaloriesBurnedEditFieldLabel.Text = 'Total Calories Burned';
            app.TotalCaloriesBurnedEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalCaloriesBurnedEditField.Editable = 'off';
            app.ActivityLevelDropDownLabel = uilabel(app.UIFigure);
            app.ActivityLevelDropDownLabel.Text = 'Activity Level';
            app.ActivityLevelDropDown = uidropdown(app.UIFigure);
            app.ActivityLevelDropDown.Items = {'Sedentary (little to no exercise)', 'Lightly active (light exercise 1–3 days per week)', 'Moderately active (moderate exercise 3–5 days per week)', 'Very active (hard exercise 6–7 days per week)', 'Extra active (very hard exercise two or more times per day, training, or a physical job)'};
            app.ActivityLevelDropDown.Value = 'Sedentary (little to no exercise)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end